classdef reflectance_fatdepth_viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        FatdepthlowerthresholdmmSlider  matlab.ui.control.Slider
        FatDepthUpperDisplay            matlab.ui.control.NumericEditField
        FatdepthupperthresholdmmSlider  matlab.ui.control.Slider
        FatdepthupperthresholdmmSliderLabel  matlab.ui.control.Label
        FatdepthlowerthresholdmmSliderLabel  matlab.ui.control.Label
        FatDepthLowerDisplay            matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        reflectanceCube = [];
        scalarAttribute = [];
        numBands = [];
        scalarAttributeMax = [];
        scalarAttributeMin = [];
        scalarPlotMask = [];
        reflectanceMaskedList = [];
        yLimits = [];
        ReflectanceAxes;
    end
    
    methods (Access = private)
        
        function plotreflectancestdscalarattribute(app)
            
            meanCurve = mean(app.reflectanceMaskedList, 1);  
            stdDev = std(app.reflectanceMaskedList, 0, 1);  
            
            % Clear the existing plot
            cla(app.UIAxes);

            % Plot the mean curve
            plot(app.UIAxes, meanCurve, 'b');

            hold(app.UIAxes, 'on');

            % Plot the shaded region (standard deviation bounds)
            x = 1:length(meanCurve);
            fill(app.UIAxes, [x fliplr(x)], [meanCurve + stdDev fliplr(meanCurve - stdDev)], 'b', 'FaceAlpha', 0.3, 'EdgeColor', 'none');

            ylabel(app.UIAxes, 'Reflectance');
            xlabel(app.UIAxes, 'Hyperspectral band number (change to wavelength)');
            grid(app.UIAxes, 'on');
            xlim(app.UIAxes, [0, app.numBands]);
            
            if ~isempty(app.yLimits)
                app.UIAxes.YLim = app.yLimits;
            end

            hold(app.UIAxes, 'off');

        end

        function newscalarplotmask(app, minValue, maxValue)
            
            app.scalarPlotMask = (app.scalarAttribute >= minValue) & (app.scalarAttribute <= maxValue);

            app.updateimage();

            [rows, cols] = find(app.scalarPlotMask);

            app.reflectanceMaskedList = zeros(length(rows), app.numBands);

            for b = 1:app.numBands
                curReflectanceList = impixel(app.reflectanceCube(:,:,b), cols, rows);
                app.reflectanceMaskedList(:, b) = curReflectanceList(:,1);
            end
        end
        
        function updateslider(app, upperValue, lowerValue)
            app.FatdepthlowerthresholdmmSlider.Value = lowerValue;
            app.FatdepthupperthresholdmmSlider.Value = upperValue;
        end

        function updatedisplay(app, upperValue, lowerValue)
            app.FatDepthLowerDisplay.Value = lowerValue;
           app.FatDepthUpperDisplay.Value = upperValue;
        end

        function updateimage(app)
            cla(app.UIAxes2);
            imshow(app.scalarPlotMask, 'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'off');
            ylabel(app.UIAxes2, '');
            xlabel(app.UIAxes2, '');
            title(app.UIAxes2, 'Binary fat depth threshold image')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, reflectancecubeX, fatdepthcubeY)
            % Get the data to the input:
            app.reflectanceCube = reflectancecubeX;
            app.scalarAttribute = fatdepthcubeY;

            [numV, numU, numBands] = size(app.reflectanceCube);
            
            app.numBands = numBands;

            % Store the the app members:
            app.scalarAttributeMax = max(app.scalarAttribute, [], "all");
            app.scalarAttributeMin = min(app.scalarAttribute(app.scalarAttribute > 0), [], "all");

            % Set up the slider's Min Max Value:
            app.FatdepthlowerthresholdmmSlider.Limits = [app.scalarAttributeMin, app.scalarAttributeMax];
            app.FatdepthupperthresholdmmSlider.Limits = [app.scalarAttributeMin, app.scalarAttributeMax];

            % Set up the initial values
            app.FatdepthlowerthresholdmmSlider.Value = app.scalarAttributeMin;
            app.FatdepthupperthresholdmmSlider.Value = app.scalarAttributeMax;

            app.FatDepthLowerDisplay.Value = app.scalarAttributeMin;
            app.FatDepthUpperDisplay.Value = app.scalarAttributeMax;
            
            app.FatdepthlowerthresholdmmSlider.MajorTicks = [app.scalarAttributeMin, app.scalarAttributeMax];
            app.FatdepthupperthresholdmmSlider.MajorTicks = [app.scalarAttributeMin, app.scalarAttributeMax];

            upperValue = app.scalarAttributeMax;
            lowerValue = app.scalarAttributeMin;

            app.updateslider(upperValue, lowerValue);
            app.updatedisplay(upperValue, lowerValue);

            app.newscalarplotmask(lowerValue, upperValue);
            app.plotreflectancestdscalarattribute();

            %get starting y limits
            app.yLimits = app.UIAxes.YLim;

        end

        % Value changed function: FatdepthupperthresholdmmSlider
        function FatdepthupperthresholdmmSliderValueChanged(app, event)
            upperValue = app.FatdepthupperthresholdmmSlider.Value;
            lowerValue = app.FatdepthlowerthresholdmmSlider.Value;
            

            % Ensure the lower value is always <= upper value
            if lowerValue > upperValue
                lowerValue = upperValue;
            end

            app.updateslider(upperValue, lowerValue);
            app.updatedisplay(upperValue, lowerValue);

            newscalarplotmask(app, lowerValue, upperValue);
            plotreflectancestdscalarattribute(app);
        end

        % Value changed function: FatdepthlowerthresholdmmSlider
        function FatdepthlowerthresholdmmSliderValueChanged(app, event)
            upperValue = app.FatdepthupperthresholdmmSlider.Value;
            lowerValue = app.FatdepthlowerthresholdmmSlider.Value;

            % Ensure the lower value is always <= upper value
            if lowerValue > upperValue
                lowerValue = upperValue;
            end

            app.updateslider(upperValue, lowerValue);
            app.updatedisplay(upperValue, lowerValue);

            newscalarplotmask(app, lowerValue, upperValue);
            plotreflectancestdscalarattribute(app);
        end

        % Value changed function: FatDepthLowerDisplay
        function FatDepthLowerDisplayValueChanged(app, event)
            lowerValue = app.FatDepthLowerDisplay.Value;
            upperValue = app.FatDepthUpperDisplay.Value;
            
            % Ensure the lower value is always <= upper value
            if lowerValue > upperValue
                lowerValue = upperValue;
            end

            if lowerValue < app.scalarAttributeMin
                lowerValue = app.scalarAttributeMin;
            end

            app.updateslider(upperValue, lowerValue);
            app.updatedisplay(upperValue, lowerValue);

            newscalarplotmask(app, lowerValue, upperValue);
            plotreflectancestdscalarattribute(app);
        end

        % Value changed function: FatDepthUpperDisplay
        function FatDepthUpperDisplayValueChanged(app, event)
            lowerValue = app.FatDepthLowerDisplay.Value;
            upperValue = app.FatDepthUpperDisplay.Value;

            % Ensure the lower value is always <= upper value
            if lowerValue > upperValue
                lowerValue = upperValue;
            end

            if upperValue > app.scalarAttributeMax
                upperValue = app.scalarAttributeMax;
            end

            app.updateslider(upperValue, lowerValue);
            app.updatedisplay(upperValue, lowerValue);

            newscalarplotmask(app, lowerValue, upperValue);
            plotreflectancestdscalarattribute(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {647, 647};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {745, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Reflectance with STD for upper and lower fat depth')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FatDepthLowerDisplay = uieditfield(app.GridLayout2, 'numeric');
            app.FatDepthLowerDisplay.ValueChangedFcn = createCallbackFcn(app, @FatDepthLowerDisplayValueChanged, true);
            app.FatdepthlowerthresholdmmSliderLabel = uilabel(app.GridLayout2);
            app.FatdepthlowerthresholdmmSliderLabel.Text = 'Fat depth lower threshold (mm)';
            app.FatdepthupperthresholdmmSliderLabel = uilabel(app.GridLayout2);
            app.FatdepthupperthresholdmmSliderLabel.Text = 'Fat depth upper threshold (mm)';
            app.FatdepthupperthresholdmmSlider = uislider(app.GridLayout2);
            app.FatdepthupperthresholdmmSlider.ValueChangedFcn = createCallbackFcn(app, @FatdepthupperthresholdmmSliderValueChanged, true);
            app.FatDepthUpperDisplay = uieditfield(app.GridLayout2, 'numeric');
            app.FatDepthUpperDisplay.ValueChangedFcn = createCallbackFcn(app, @FatDepthUpperDisplayValueChanged, true);
            app.FatdepthlowerthresholdmmSlider = uislider(app.GridLayout2);
            app.FatdepthlowerthresholdmmSlider.ValueChangedFcn = createCallbackFcn(app, @FatdepthlowerthresholdmmSliderValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes2 = uiaxes(app.GridLayout3);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reflectance_fatdepth_viewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end